classdef finalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ObjectDiscriminatorButton  matlab.ui.control.Button
        CandidateDetectionButton   matlab.ui.control.Button
        FrameNumber                matlab.ui.control.EditField
        LoadFrameButton            matlab.ui.control.Button
        NumUnassignedT             matlab.ui.control.NumericEditField
        RunAlgorithmButton         matlab.ui.control.Button
        LoadFolderButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainDir; % Main directory of the images & data
        numFrames;
        images; %array of all images in folder
        greyImages; %array of all gry images
        BinImages; %array of all binary images
        Tracks;
        Covariances;
        centroids;
        bboxes;
        rows;
        cols;
        predictions;
        qk = [1, 0,0,0,0,0;
              0,1,0,0,0,0;
              0,0,1,0,0,0;
              0,0,0,1,0,0;
              0,0,0,0,1,0;
              0,0,0,0,0,1];
        hk = [1,0,0,0,0,0; 0,1,0,0,0,0];
        rk = [1,0; 0, 1];
        fk = [1,0,1,0,0.5,0;
              0,1,0,1,0,0.5;
              0,0,1,0,1,0;
              0,0,0,1,0,1;
              0,0,0,0,1,0;
              0,0,0,0,0,1];
        I = eye(6);
    end
    
    methods (Access = private)
        
        function ObjectDetection(app, frames, display)
             for i= 2: frames - 1
                %intialise images at index i, i-1 and i+1
                gry1 = app.greyImages{1,i};
                gry2 = app.greyImages{1, i-1};
                gry3 = app.greyImages{1, i+1};
                %Split images into regions of 30x30 pixels
                [app.rows, app.cols] = size(gry1);
                wholeBlockRows = floor(app.rows/30);
                blockVectorR = [30*ones(1, wholeBlockRows), rem(app.rows, 30)];
                wholeBlockCols = floor(app.cols/30);
                blockVectorC = [30 * ones(1, wholeBlockCols), rem(app.cols, 30)];
                ca1 = mat2cell(gry1, blockVectorR, blockVectorC);
                ca2 = mat2cell(gry2, blockVectorR, blockVectorC);
                ca3 = mat2cell(gry3, blockVectorR, blockVectorC);
                numBlocksR = size(ca1, 1);
                numBlocksC = size(ca1, 2);
                binary_array = cell(numBlocksR, numBlocksC);
                %Apply algorithm at each block
                for r = 1: numBlocksR
                    for c = 1: numBlocksC
                        diff1 = abs(double(ca1{r,c}) - double(ca2{r,c}));
                        diff2 = abs(double(ca1{r,c})- double(ca3{r,c}));
                        avg1 = mean(diff1, "all");
                        avg2 = mean(diff2, "all");
                        thresh1 = -log(0.05)*avg1;
                        thresh2 = -log(0.05)*avg2;
                        binary1 = diff1 >thresh1;
                        binary2 = diff2 >thresh2;
                        binary_array{r,c} = binary1.*binary2;
                        
                    end
                end
                 %display binarized image
                 final = cell2mat(binary_array);
                 app.BinImages{1, i-1} = final;
                 if display
                    imshow(final, 'Parent', app.UIAxes);
                     drawnow;
                     pause(0.01);
                 end
             end
        end
        
        function ObjectDiscrimination(app, display)
            
            for h =1: length(app.BinImages)
                Hblob = vision.BlobAnalysis('MinimumBlobArea', 4, 'LabelMatrixOutputPort', 300, 'MaximumCount', 300);
                [area, centroid, bbox] = Hblob(imbinarize(app.BinImages{1,h}));
                region_grown = zeros(app.rows, app.cols);
                bbox = zeros(size(centroid, 1), 6);
                for row = 1:size(centroid,1)
                    centroidCol = round(centroid(row, 1));
                    centroidRow = round(centroid(row, 2));
                    lowerRindex = max(1, centroidRow - 5);
                    upperRindex = min(app.rows, centroidRow + 5);
                    lowerCindex = max(1, centroidCol - 5);
                    upperCindex = min(app.cols, centroidCol + 5);
                    gray_window = app.greyImages{1,h}(lowerRindex: upperRindex, lowerCindex : upperCindex);
                    bin_window = app.BinImages{1,h}(lowerRindex: upperRindex, lowerCindex : upperCindex);
                    bbox(row, :) = [centroidCol, centroidRow, lowerCindex, lowerRindex, upperCindex - lowerCindex, upperRindex - lowerRindex];
                    pixel_values = zeros(numel(gray_window), 1);
                    a = 1;
                    for k = 1:size(gray_window, 1)
                        for s = 1:size(gray_window, 2)
                            if bin_window(k,s) == 1
                                pixel_values(a) = gray_window(k,s);
                                a = a + 1;
                            end
                        end
                    end
%                     pixel_values = pixel_values(1:a-1);
                    mu = mean(double(pixel_values), "all");
                    sigma = std(double(pixel_values), 0, "all");
                    x = norminv([0.005, 0.995], mu, sigma);
                    for k = 1:size(gray_window, 1)
                        for s = 1:size(gray_window, 2)
                            if gray_window(k,s) > x(1) && gray_window(k,s) < x(2)
                                bin_window(k,s) = 1;
                            else
                                bin_window(k,s) = 0;
                            end
                        end
                    end
                    region_grown(lowerRindex:upperRindex, lowerCindex: upperCindex) = bin_window;
                end
                
                %Morphological cues
                Hblob = vision.BlobAnalysis('MinimumBlobArea', 10, 'LabelMatrixOutputPort', 300, 'MaximumCount', 300, "MajorAxisLengthOutputPort", true, "EccentricityOutputPort", true, "ExtentOutputPort", true);
                [area, centroid, bbox, majoraxis, eccentricity, extent] = Hblob(imbinarize(region_grown));
                toRemove = [];
                for row = 1:size(centroid, 1)
                    if extent(row) < 0.4
                        toRemove = [toRemove; row];
                        continue;
                    end
%                     if eccentricity(row) < 0.4
%                         toRemove = [toRemove; row];
%                         continue;
%                     end
                    %filter by major axis
                    if majoraxis(row) < 10
                        toRemove = [toRemove; row];
                        continue;
                    end
                    if area(row) < 25
                        toRemove = [toRemove; row];
                        continue;
                    end
                end
                % Remove rows of zeros
                centroid(toRemove, :) = [];
                bbox(toRemove, :) = [];
                app.bboxes{1, h} = bbox;
                app.centroids{1,h} = centroid;
                %show result
                if display
                    imshow(region_grown, 'Parent', app.UIAxes);
                    for row = 1:size(centroid,1)
                        
                        rectangle(app.UIAxes, "Position", [bbox(row, 1), bbox(row, 2), bbox(row, 3), bbox(row, 4)], "EdgeColor", 'b');
                    end
                    pause(0.01);
                end
            end       
        end
        
        function [assignment, unassignedTracks, unassignedDetections] = TrackHypothesis(app,predictions, detections)
            cost = zeros(size(predictions,1), size(detections,1));
            for i = 1: size(predictions,1)
                diff = detections - repmat(predictions(i, :), [size(detections,1), 1]);
                cost(i, :) = sqrt(sum(diff.^2,2));
            end
            [assignment, unassignedTracks, unassignedDetections] = assignDetectionsToTracks(cost, 0.7);
        end
        
        function KalmanFilter(app, assignment, unassignedTracks, unassignedDetections, bbox, detections, i)
            if ~isempty(unassignedDetections)
                for b = 1: length(unassignedDetections)
                    %initialise tracks
                    unassignedIndex = unassignedDetections(b);
                    app.Tracks{1, unassignedIndex} = [detections(unassignedIndex, 1); detections(unassignedIndex,2);0;0;0;0];
                    app.Covariances{1, unassignedIndex} = app.qk;
                    app.predictions(unassignedIndex, 1:2) = [detections(unassignedIndex,1), detections(unassignedIndex,2)];
                end
            end
            if ~isempty(assignment)
                for y=1: size(assignment, 1)
                    %update step for kalman filter, display and then predict
                    assignedIndextrack = assignment(y,1);
                    assignedIndexdetection = assignment(y,2);
                    %compute error
                    yk = [detections(assignedIndexdetection, 1); detections(assignedIndexdetection, 2)] - app.hk*app.Tracks{1, assignedIndextrack};
                    %compute covariance of innovation
                    sk = (app.hk*app.Covariances{1, assignedIndextrack})*transpose(app.hk)+ app.rk;
                    %compute kalman gain
                    kk = app.Covariances{1, assignedIndextrack}*transpose(app.hk)*sk^(-1);
                    %compute updated state estimate
                    app.Tracks{1, assignedIndextrack} = app.Tracks{1, assignedIndextrack} + kk*yk;
                    app.predictions(assignedIndextrack, 1:2) = app.Tracks{1, assignedIndextrack}(1:2, :);
                    %compute updated state covariance
                    app.Covariances{1, assignedIndextrack} = (app.I - kk*app.hk)*app.Covariances{1, assignedIndextrack};    
                end
            end
            if ~isempty(unassignedTracks)
                for d =1: length(unassignedTracks)
                    %nearest search
                    unassignedTindex = unassignedTracks(d);
                    %location of object from previous frame
                    previoustrack = app.fk^(-1)*trackunassigned;
                    %create a 5x5 search region
                    lowerRindex = max(1, previoustrack(1,1) - 2);
                    upperRindex = min(app.rows,previoustrack(1,1) +2 );
                    lowerCindex = max(1, previoustrack (2,1) - 2);
                    upperCindex = min(app.cols, previoustrack(2,1)+2);
                    previousFrame = app.greyImages{1,i-1}(lowerRindex:upperRindex, lowerCindex:upperCindex); %search window in previous frame
                    currentFrame = app.greyImages{1, i}(lowerRindex:upperRindex, lowerCindex:upperCindex); %search window in current frame
                    %compute SAD
                    difference = abs(currentFrame - previousFrame);
                    SAD = sum(difference, 'all');
                    if SAD <= 150 %average individual pixel variation of 6 is maximal variation allowed
                        %hypothesis found, update step for Kalman
                        yk = [previoustrack(1,1); previoustrack(2,1)] - app.hk*previoustrack;
                        pk = app.Covariances{1, unassignedTindex};
                        hkt = transpose(app.hk);
                        sk = app.hk*pk*hkt + app.rk;
                        kk = pk*hkt*sk^(-1);
                        app.Tracks{1, unassignedTindex} = app.Tracks{1, unassignedTindex} + kk*yk;
                        app.predictions(unassignedTindex, 1:2) = app.Tracks{1, unassignedTindex}(1:2,:);
                        app.Covariances{1, unassignedTindex} = (app.I - kk*app.hk)*app.Covariances{1, unassignedTindex};
                    else
                        %remove tracks
                        app.Tracks(: ,unassignedTindex)= [];
                        app.predictions(unassignedTindex, :) = [];
                    end
                end
                    
            end
            imshow(app.images{1,i},'Parent', app.UIAxes);
            
            %display results
            for e =1 : length(bbox)
                posx = bbox(e,1);
                posy = bbox(e,2);
                width = bbox(e, 3);
                height = bbox(e,4);
                rectangle(app.UIAxes, "Position", [posx, posy,width, height],"EdgeColor", 'y');
            end
            %predict next track
            for g=1: length(app.Tracks)
                app.Tracks{1,g} = app.fk*app.Tracks{1,g};
                app.predictions(g, 1:2) = app.Tracks{1,g}(1:2, :);
                app.Covariances{1,g} = app.fk*app.Covariances{1,g}*transpose(app.fk)+app.qk;
            end
        end
        
        function data = getFrameData(app, frameNumber)
            gtLocation = sprintf("%s\\gt\\gt.txt" ,app.MainDir);
            
            % Variables for loop
            opts = detectImportOptions(gtLocation);
            n = true;
            a = 1;

            % Preallocate data array
            data = zeros(100, 6);
            while n
                % Only read line a of file
                opts.DataLines = [a a];
                A = readmatrix(gtLocation, opts);
                if A(1) > frameNumber
                    n = false;
                end
                if A(1) == frameNumber
                    % Only first six columns are needed
                    data(a,:) = A(1:6);
                end
                a = a+1;
            end
            % Remove rows of zeros
            data( all(~data,2), : ) = [];  
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            app.MainDir = uigetdir("Select Numbered Folder");
            %get image files and number of images
            image_files = dir(sprintf('%s\\img\\*.jpg', app.MainDir));
            for i = 1: length(image_files)
                path = strcat(image_files(i).folder, '\', image_files(i).name) ;
                im = imread(path); %store all images in cell array
                app.images{1, i} = im;
                gry = rgb2gray(im);
                app.greyImages{1,i} = gry; %store greyscale versions of images in cell array
            end
            app.numFrames = length(image_files);
            [app.rows, app.cols] = size(app.greyImages{1,1}); %assumes all images in file are same size
        end

        % Button pushed function: RunAlgorithmButton
        function RunAlgorithmButtonPushed(app, event)
            % Object Detection
            ObjectDetection(app, app.numFrames, false);
            ObjectDiscrimination(app, false);
            for frame=1: length(app.BinImages)
                %Hypothesis
                detections = [app.centroids{1,frame}(:,2), app.centroids{1,frame}(:, 1)];
                bbox = app.bboxes{1,frame};
                [assignment, unassignedTracks, unassignedDetections] = TrackHypothesis(app, app.predictions, detections);
                %Kalman filter
                KalmanFilter(app, assignment, unassignedTracks, unassignedDetections, bbox, detections, frame);
                pause(1);
            end
        end

        % Button pushed function: LoadFrameButton
        function LoadFrameButtonPushed(app, event)
            frame = str2double(app.FrameNumber.Value);
            image_files = dir(sprintf('%s\\img\\*.jpg', app.MainDir));
            path = strcat(image_files(frame).folder, '\', image_files(frame).name);
            image = imread(path);
            data = getFrameData(app, frame);
            imshow(image, 'Parent', app.UIAxes);
            for row = 1:size(data,1)
                posx = data(row, 3);
                posy = data(row, 4);
                width = data(row, 5);
                height = data(row, 6);
                rectangle(app.UIAxes, "Position", [posx, posy, width, height], "EdgeColor", 'y');
            end
        end

        % Button pushed function: CandidateDetectionButton
        function CandidateDetectionButtonPushed(app, event)
            ObjectDetection(app, app.numFrames, true);
        end

        % Button pushed function: ObjectDiscriminatorButton
        function ObjectDiscriminatorButtonPushed(app, event)
            ObjectDiscrimination(app, true);
            a = "finished"
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.LoadFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.RunAlgorithmButton = uibutton(app.UIFigure, 'push');
            app.RunAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunAlgorithmButtonPushed, true);
            app.RunAlgorithmButton.Text = 'Run Algorithm';
            app.NumUnassignedT = uieditfield(app.UIFigure, 'numeric');
            app.LoadFrameButton = uibutton(app.UIFigure, 'push');
            app.LoadFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFrameButtonPushed, true);
            app.LoadFrameButton.Text = 'Load Frame';
            app.FrameNumber = uieditfield(app.UIFigure, 'text');
            app.CandidateDetectionButton = uibutton(app.UIFigure, 'push');
            app.CandidateDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CandidateDetectionButtonPushed, true);
            app.CandidateDetectionButton.Text = 'Candidate Detection';
            app.ObjectDiscriminatorButton = uibutton(app.UIFigure, 'push');
            app.ObjectDiscriminatorButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDiscriminatorButtonPushed, true);
            app.ObjectDiscriminatorButton.Text = 'Object Discriminator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end