classdef finalProjectv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ObjectDiscriminatorButton  matlab.ui.control.Button
        CandidateDetectionButton   matlab.ui.control.Button
        FrameNumber                matlab.ui.control.EditField
        LoadFrameButton            matlab.ui.control.Button
        RunAlgorithmButton         matlab.ui.control.Button
        LoadFolderButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainDir; % Main directory of the images & data
        numFrames;
        images; %array of all images in folder
        greyImages; %array of all gry images
        BinImages; %array of all binary images
        Tracks;
        centroids;
        bboxes;
        rows;
        cols;
        gatingThresh         = 0.9;              % A threshold to reject a candidate match between a detection and a track.
        gatingCost           = 100;              % A large value for the assignment cost matrix that enforces the rejection of a candidate match.
        costOfNonAssignment  = 10;               % A tuning parameter to control the likelihood of creation of a new track.
        timeWindowSize       = 16;               % A tuning parameter to specify the number of frames required to stabilize the confidence score of a track.
        confidenceThresh     = 2;                % A threshold to determine if a track is true positive or false alarm.
        ageThresh            = 8;                % A threshold to determine the minimum length required for a track being true positive.
        visThresh            = 0.6;  
    end
    
    methods (Access = private)
        
        function ObjectDetection(app, frames, display)
             for i= 2: frames - 1
                %intialise images at index i, i-1 and i+1
                gry1 = app.greyImages{1,i};
                gry2 = app.greyImages{1, i-1};
                gry3 = app.greyImages{1, i+1};
                %Split images into regions of 30x30 pixels
                [app.rows, app.cols] = size(gry1);
                wholeBlockRows = floor(app.rows/30);
                blockVectorR = [30*ones(1, wholeBlockRows), rem(app.rows, 30)];
                wholeBlockCols = floor(app.cols/30);
                blockVectorC = [30 * ones(1, wholeBlockCols), rem(app.cols, 30)];
                ca1 = mat2cell(gry1, blockVectorR, blockVectorC);
                ca2 = mat2cell(gry2, blockVectorR, blockVectorC);
                ca3 = mat2cell(gry3, blockVectorR, blockVectorC);
                numBlocksR = size(ca1, 1);
                numBlocksC = size(ca1, 2);
                binary_array = cell(numBlocksR, numBlocksC);
                %Apply algorithm at each block
                for r = 1: numBlocksR
                    for c = 1: numBlocksC
                        diff1 = abs(double(ca1{r,c}) - double(ca2{r,c}));
                        diff2 = abs(double(ca1{r,c})- double(ca3{r,c}));
                        avg1 = mean(diff1, "all");
                        avg2 = mean(diff2, "all");
                        thresh1 = -log(0.05)*avg1;
                        thresh2 = -log(0.05)*avg2;
                        binary1 = diff1 >thresh1;
                        binary2 = diff2 >thresh2;
                        binary_array{r,c} = binary1.*binary2;
                        
                    end
                end
                 %display binarized image
                 final = cell2mat(binary_array);
                 app.BinImages{1, i-1} = final;
                 if display
                    imshow(final, 'Parent', app.UIAxes);
                     drawnow;
                     pause(0.01);
                 end
             end
        end
        
        function ObjectDiscrimination(app, display)
            
            for h =1: length(app.BinImages)
                Hblob = vision.BlobAnalysis('MinimumBlobArea', 4, 'LabelMatrixOutputPort', 300, 'MaximumCount', 300);
                [area, centroid, bbox] = Hblob(imbinarize(app.BinImages{1,h}));
                region_grown = zeros(app.rows, app.cols);
                bbox = zeros(size(centroid, 1), 6);
                for row = 1:size(centroid,1)
                    centroidCol = round(centroid(row, 1));
                    centroidRow = round(centroid(row, 2));
                    lowerRindex = max(1, centroidRow - 5);
                    upperRindex = min(app.rows, centroidRow + 5);
                    lowerCindex = max(1, centroidCol - 5);
                    upperCindex = min(app.cols, centroidCol + 5);
                    gray_window = app.greyImages{1,h}(lowerRindex: upperRindex, lowerCindex : upperCindex);
                    bin_window = app.BinImages{1,h}(lowerRindex: upperRindex, lowerCindex : upperCindex);
                    bbox(row, :) = [centroidCol, centroidRow, lowerCindex, lowerRindex, upperCindex - lowerCindex, upperRindex - lowerRindex];
                    pixel_values = zeros(numel(gray_window), 1);
                    a = 1;
                    for k = 1:size(gray_window, 1)
                        for s = 1:size(gray_window, 2)
                            if bin_window(k,s) == 1
                                pixel_values(a) = gray_window(k,s);
                                a = a + 1;
                            end
                        end
                    end
                    mu = mean(double(pixel_values), "all");
                    sigma = std(double(pixel_values), 0, "all");
                    x = norminv([0.005, 0.995], mu, sigma);
                    for k = 1:size(gray_window, 1)
                        for s = 1:size(gray_window, 2)
                            if gray_window(k,s) > x(1) && gray_window(k,s) < x(2)
                                bin_window(k,s) = 1;
                            else
                                bin_window(k,s) = 0;
                            end
                        end
                    end
                    region_grown(lowerRindex:upperRindex, lowerCindex: upperCindex) = bin_window;
                end
                
                %Morphological cues
                Hblob = vision.BlobAnalysis('MinimumBlobArea', 10, 'LabelMatrixOutputPort', 300, 'MaximumCount', 300, "MajorAxisLengthOutputPort", true, "EccentricityOutputPort", true, "ExtentOutputPort", true);
                [area, centroid, bbox, majoraxis, eccentricity, extent] = Hblob(imbinarize(region_grown));
                toRemove = [];
                for row = 1:size(centroid, 1)
                    %filter by extent
                    if extent(row) < 0.4
                        toRemove = [toRemove; row];
                        continue;
                    end
                    %filter by eccentricity
%                     if eccentricity(row) < 0.4
%                         toRemove = [toRemove; row];
%                         continue;
%                     end
                    %filter by major axis
                    if majoraxis(row) < 10
                        toRemove = [toRemove; row];
                        continue;
                    end
                    %filter by area
                    if area(row) < 25
                        toRemove = [toRemove; row];
                        continue;
                    end
                end
                % Remove rows of zeros
                centroid(toRemove, :) = [];
                bbox(toRemove, :) = [];
                app.bboxes{1, h} = bbox;
                app.centroids{1,h} = centroid;
                %show result
                if display
                    imshow(region_grown, 'Parent', app.UIAxes);
                    for row = 1:size(centroid,1)
                        
                        rectangle(app.UIAxes, "Position", [bbox(row, 1), bbox(row, 2), bbox(row, 3), bbox(row, 4)], "EdgeColor", 'b');
                    end
                    pause(0.01);
                end
            end       
        end
        
        function [assignment, unassignedTracks, unassignedDetections] = TrackHypothesis(app)
            % Code adopted/inspired from Mathworks.com
            predBboxes = reshape([app.Tracks(:).predPosition], 4, []);
            cost = 1 - bboxOverlapRatio(predBboxes, app.bboxes);

            cost(cost > app.gatingThresh) = 1 + app.gatingCost;

            [assignment, unassignedTracks, unassignedDetections] = assignDetectionsToTracks(cost, app.costOfNonAssignment);
        end
        
        function deleteLostTracks(app)
            if isempty(app.Tracks)
                return;
            end

            % Compute the fraction of the track's age for which it was visible.
            ages = [app.Tracks(:).age]';
            totalVisibleCounts = [app.Tracks(:).totalVisibleCount]';
            visibility = totalVisibleCounts ./ ages;
    
            % Check the maximum detection confidence score.
            confidence = reshape([app.Tracks(:).confidence], 2, [])';
            maxConfidence = confidence(:, 1);
    
            % Find the indices of 'lost' tracks.
            lostInds = (ages <= option.ageThresh & visibility <= option.visThresh) | ...
                 (maxConfidence <= option.confidenceThresh);
    
            % Delete lost tracks.
            app.Tracks = app.Tracks(~lostInds);
        end

        function createNewTracks(app, unassignedDetections)
            % Code adopted/inspired from Mathworks.com
            unassignedCentroids = centroids(unassignedDetections, :);
            unassignedBboxes = bboxes(unassignedDetections, :);
            unassignedScores = scores(unassignedDetections);

            for i = 1:size(unassignedBboxes, 1)
                centroid = unassignedCentroids(i,:);
                bbox = unassignedBboxes(i, :);
                score = unassignedScores(i);
    
                % Create a Kalman filter object.
                kalmanFilter = configureKalmanFilter('ConstantVelocity', ...
                    centroid, [2, 1], [5, 5], 100);
    
                % Create a new track.
                newTrack = struct(...
                    'id', nextId, ...
                    'color', 255*rand(1,3), ...
                    'bboxes', bbox, ...
                    'scores', score, ...
                    'kalmanFilter', kalmanFilter, ...
                    'age', 1, ...
                    'totalVisibleCount', 1, ...
                    'confidence', [score, score], ...
                    'predPosition', bbox);
    
                % Add it to the array of tracks.
                app.Tracks(end + 1) = newTrack; 
    
                % Increment the next id.
                nextId = nextId + 1;
            end
        end
        
        function updateAssignedTracks(app)
            % Code adopted/inspired from Mathworks.com
            numAssignedTracks = size(assignments, 1);
            for i = 1:numAssignedTracks
                trackIdx = assignments(i, 1);
                detectionIdx = assignments(i, 2);
        
                centroid = centroids(detectionIdx, :);
                bbox = bboxes(detectionIdx, :);
        
                correct(app.Tracks(trackIdx).kalmanFilter, centroid);
        
                T = min(size(app.Tracks(trackIdx).bboxes,1), 4);
                w = mean([app.Tracks(trackIdx).bboxes(end-T+1:end, 3); bbox(3)]);
                h = mean([app.Tracks(trackIdx).bboxes(end-T+1:end, 4); bbox(4)]);
                app.Tracks(trackIdx).bboxes(end+1,:) = [centroid - [w, h]/2, w, h];
        
                % Update track's age.
                app.Tracks(trackIdx).age = app.Tracks(trackIdx).age + 1;
        
                % Update track's score history
                app.Tracks(trackIdx).scores = [app.Tracks(trackIdx).scores; scores(detectionIdx)];
        
                % Update visibility.
                app.Tracks(trackIdx).totalVisibleCount = ...
                    app.Tracks(trackIdx).totalVisibleCount + 1;
        
                % Adjust track confidence score based on the maximum detection
                % score in the past 'timeWindowSize' frames.
                T = min(option.timeWindowSize, length(app.Tracks(trackIdx).scores));
                score = app.Tracks(trackIdx).scores(end-T+1:end);
                app.Tracks(trackIdx).confidence = [max(score), mean(score)];
            end
        end
        
        function updateUnassignedTracks(app)
            for i = 1:length(unassignedTracks)
                idx = unassignedTracks(i);
                app.Tracks(idx).age = app.Tracks(idx).age + 1;
                app.Tracks(idx).bboxes = [app.Tracks(idx).bboxes; app.Tracks(idx).predPosition];
                app.Tracks(idx).scores = [app.Tracks(idx).scores; 0];
    
                % Adjust track confidence score based on the maximum detection
                % score in the past 'timeWindowSize' frames
                T = min(option.timeWindowSize, length(app.Tracks(idx).scores));
                score = app.Tracks(idx).scores(end-T+1:end);
                app.Tracks(idx).confidence = [max(score), mean(score)];
            end
        end
        
        function displayTrackingResults(app)
            % Code adopted/inspired from Mathworks.com
            displayRatio = 4/3;
            frame = imresize(frame, displayRatio);
    
            if ~isempty(app.Tracks)
                ages = [app.Tracks(:).age]';
                confidence = reshape([app.Tracks(:).confidence], 2, [])';
                maxConfidence = confidence(:, 1);
                avgConfidence = confidence(:, 2);
                opacity = min(0.5,max(0.1,avgConfidence/3));
                noDispInds = (ages < option.ageThresh & maxConfidence < option.confidenceThresh) | ...
                           (ages < option.ageThresh / 2);
    
                for i = 1:length(app.Tracks)
                    if ~noDispInds(i)
    
                        % scale bounding boxes for display
                        bb = app.Tracks(i).bboxes(end, :);
                        bb(:,1:2) = (bb(:,1:2)-1)*displayRatio + 1;
                        bb(:,3:4) = bb(:,3:4) * displayRatio;
    
    
                        frame = insertShape(frame, ...
                                                'FilledRectangle', bb, ...
                                                'Color', app.Tracks(i).color, ...
                                                'Opacity', opacity(i));
                        frame = insertObjectAnnotation(frame, ...
                                                'rectangle', bb, ...
                                                num2str(avgConfidence(i)), ...
                                                'Color', app.Tracks(i).color);
                    end
                end
            end
    
            frame = insertShape(frame, 'Rectangle', option.ROI * displayRatio, ...
                                    'Color', [255, 0, 0], 'LineWidth', 3);
    
            step(obj.videoPlayer, frame);

        end

        function KalmanFilter(app, assignment, unassignedTracks, unassignedDetections, bbox, detections, i)
            if ~isempty(unassignedDetections)
                createNewTracks(app, unassignedDetections);
            end
            if ~isempty(assignment)
                updateAssignedTracks(app)
            end
            if ~isempty(unassignedTracks)
                updateUnassignedTracks(app)  
            end
            %display results
            displayTrackingResults(app);
        end
        
        function data = getFrameData(app, frameNumber)
            gtLocation = sprintf("%s\\gt\\gt.txt" ,app.MainDir);
            
            % Variables for loop
            opts = detectImportOptions(gtLocation);
            n = true;
            a = 1;

            % Preallocate data array
            data = zeros(100, 6);
            while n
                % Only read line a of file
                opts.DataLines = [a a];
                A = readmatrix(gtLocation, opts);
                if A(1) > frameNumber
                    n = false;
                end
                if A(1) == frameNumber
                    % Only first six columns are needed
                    data(a,:) = A(1:6);
                end
                a = a+1;
            end
            % Remove rows of zeros
            data( all(~data,2), : ) = [];  
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            app.MainDir = uigetdir("Select Numbered Folder");
            %get image files and number of images
            image_files = dir(sprintf('%s\\img\\*.jpg', app.MainDir));
            for i = 1: length(image_files)
                path = strcat(image_files(i).folder, '\', image_files(i).name) ;
                im = imread(path); %store all images in cell array
                app.images{1, i} = im;
                gry = rgb2gray(im);
                app.greyImages{1,i} = gry; %store greyscale versions of images in cell array
            end
            app.numFrames = length(image_files);
            [app.rows, app.cols] = size(app.greyImages{1,1}); %assumes all images in file are same size
        end

        % Button pushed function: RunAlgorithmButton
        function RunAlgorithmButtonPushed(app, event)
            % Object Detection
            ObjectDetection(app, app.numFrames, false);
            ObjectDiscrimination(app, false);
            for frame=1: length(app.BinImages)
                %Hypothesis
                detections = [app.centroids{1,frame}(:,2), app.centroids{1,frame}(:, 1)];
                bbox = app.bboxes{1,frame};
                [assignment, unassignedTracks, unassignedDetections] = TrackHypothesis(app);
                %Kalman filter
                KalmanFilter(app, assignment, unassignedTracks, unassignedDetections, bbox, detections, frame);
                pause(1);
            end
        end

        % Button pushed function: LoadFrameButton
        function LoadFrameButtonPushed(app, event)
            frame = str2double(app.FrameNumber.Value);
            image_files = dir(sprintf('%s\\img\\*.jpg', app.MainDir));
            path = strcat(image_files(frame).folder, '\', image_files(frame).name);
            image = imread(path);
            data = getFrameData(app, frame);
            imshow(image, 'Parent', app.UIAxes);
            for row = 1:size(data,1)
                posx = data(row, 3);
                posy = data(row, 4);
                width = data(row, 5);
                height = data(row, 6);
                rectangle(app.UIAxes, "Position", [posx, posy, width, height], "EdgeColor", 'y');
            end
        end

        % Button pushed function: CandidateDetectionButton
        function CandidateDetectionButtonPushed(app, event)
            ObjectDetection(app, app.numFrames, true);
        end

        % Button pushed function: ObjectDiscriminatorButton
        function ObjectDiscriminatorButtonPushed(app, event)
            ObjectDiscrimination(app, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.LoadFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.RunAlgorithmButton = uibutton(app.UIFigure, 'push');
            app.RunAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunAlgorithmButtonPushed, true);
            app.RunAlgorithmButton.Text = 'Run Algorithm';
            app.LoadFrameButton = uibutton(app.UIFigure, 'push');
            app.LoadFrameButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFrameButtonPushed, true);
            app.LoadFrameButton.Text = 'Load Frame';
            app.FrameNumber = uieditfield(app.UIFigure, 'text');
            app.CandidateDetectionButton = uibutton(app.UIFigure, 'push');
            app.CandidateDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CandidateDetectionButtonPushed, true);
            app.CandidateDetectionButton.Text = 'Candidate Detection';
            app.ObjectDiscriminatorButton = uibutton(app.UIFigure, 'push');
            app.ObjectDiscriminatorButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDiscriminatorButtonPushed, true);
            app.ObjectDiscriminatorButton.Text = 'Object Discriminator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProjectv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end