classdef projectTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        FrameNumber                matlab.ui.control.EditField
        ShowFrameButton            matlab.ui.control.Button
        MatchDiscriminationButton  matlab.ui.control.Button
        ObjectDetectionButton      matlab.ui.control.Button
        LoadFolderButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Maindir; %main directory
        image_files; %frames of selected folder
        numFrames; %number of frames in the folder
        binary_images; %list of binary images after small object detection
    end
    
    methods (Access = private)
        
        function data = getFrameData(app, frameNumber)
            %GETFRAMEDATA retrieves frame data on object locations
            % frameNumber specifies which frame to analyse

            gtLocation = sprintf("%s\\gt\\gt.txt" ,app.Maindir);
            
            % Variables for loop
            opts = detectImportOptions(gtLocation);
            n = true;
            a = 1;

            % Preallocate data array
            data = zeros(100, 6);
            while n
                % Only read line a of file
                opts.DataLines = [a a];
                A = readmatrix(gtLocation, opts);
                if A(1) > frameNumber
                    n = false;
                end
                if A(1) == frameNumber
                    % Only first six columns are needed
                    data(a,:) = A(1:6);
                end
                a = a+1;
            end
            % Remove rows of zeros
            data( all(~data,2), : ) = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            %Select Numbered Folder
            app.Maindir = uigetdir("Select Numbered Foldeer");          
            app.image_files = dir(sprintf('%s\\img\\*.jpg', app.Maindir));
            app.numFrames = length(app.image_files);
%             for i = 1: app.numFrames
%                 path = strcat(app.image_files(i).folder, '\', app.image_files(i).name);
%                 image = imread(path);
%                 imshow(image, 'Parent',app.UIAxes);
%                 drawnow;
%                 pause(0.01)
%             end
            
        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionButtonPushed(app, event)
             %Split image into 30x30 pixel regions
             for i= 2: app.numFrames - 1
                 %intialise images at index i, i-1 and i+1
                path1 = strcat(app.image_files(i).folder, '\', app.image_files(i).name);
                path2 = strcat(app.image_files(i-1).folder, '\', app.image_files(i-1).name);
                path3 = strcat(app.image_files(i+1).folder, '\', app.image_files(i+1).name);
                image1 = imread(path1);
                image2 = imread(path2);
                image3 = imread(path3);
                gry1 = rgb2gray(image1);
                gry2 = rgb2gray(image2);
                gry3 = rgb2gray(image3);
                %Split images into regions of 30x30 pixels
                [rows, cols] = size(gry1);
                wholeBlockRows = floor(rows/30);
                blockVectorR = [30*ones(1, wholeBlockRows), rem(rows, 30)];
                wholeBlockCols = floor(cols/30);
                blockVectorC = [30 * ones(1, wholeBlockCols), rem(cols, 30)];
                ca1 = mat2cell(gry1, blockVectorR, blockVectorC);
                ca2 = mat2cell(gry2, blockVectorR, blockVectorC);
                ca3 = mat2cell(gry3, blockVectorR, blockVectorC);
                numBlocksR = size(ca1, 1);
                numBlocksC = size(ca1, 2);
                binary_array = cell(numBlocksR, numBlocksC);
                %Apply algorithm at each block
                for r = 1: numBlocksR
                    for c = 1: numBlocksC
                        diff1 = abs(double(ca1{r,c}) - double(ca2{r,c}));
                        diff2 = abs(double(ca1{r,c})- double(ca3{r,c}));
                        avg1 = mean(diff1, "all");
                        avg2 = mean(diff2, "all");
                        thresh1 = -log(0.05)*avg1;
                        thresh2 = -log(0.05)*avg2;
                        binary1 = diff1 >thresh1;
                        binary2 = diff2 >thresh2;
                        binary_array{r,c} = binary1.*binary2;
                        
                    end
                end
                 %display binarized image
                 final = cell2mat(binary_array);
                 imshow(final, 'Parent', app.UIAxes);
                 drawnow;
                 pause(0.01);
            end
% for i = 2: app.numFrames-1
%                 path1 = strcat(app.image_files(i).folder, '\', app.image_files(i).name);
%                 path2 = strcat(app.image_files(i-1).folder, '\', app.image_files(i-1).name);
%                 path3 = strcat(app.image_files(i+1).folder, '\', app.image_files(i+1).name);
%                 im1 = imread(path1);
%                 im2 = imread(path2);
%                 im3 = imread(path3);
%                 gry1 = rgb2gray(im1);
%                 gry2 = rgb2gray(im2);
%                 gry3 = rgb2gray(im3);
%                 diff1 = abs(double(gry1)- double(gry2));
%                 diff2 = abs(double(gry1)- double(gry3));
%                 avg1 = mean(diff1, "all");
%                 avg2 = mean(diff2, "all");
%                 thresh1 = -log(0.05)*avg1;
%                 thresh2 = -log(0.05)*avg2;
%                 binary1 = diff1 > thresh1;
%                 binary2 = diff2 > thresh2;
%                 final = binary1.*binary2;
%                 imshow(final, 'Parent', app.UIAxes);
%                 drawnow;
%                 pause(0.01);
% end
        end

        % Button pushed function: ShowFrameButton
        function ShowFrameButtonPushed(app, event)
            % Displays Frame
            frame = str2double(app.FrameNumber.Value);
            path = strcat(app.image_files(frame).folder, '\', app.image_files(frame).name);
            image = imread(path);
            data = getFrameData(app, frame);
            imshow(image, 'Parent', app.UIAxes);
            for row = 1:size(data,1)
                posx = data(row, 3);
                posy = data(row, 4) - data(row, 6);
                width = data(row, 5);
                height = data(row, 6);
                rectangle(app.UIAxes, "Position", [posx, posy, width, height], "EdgeColor", 'y');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.LoadFolderButton = uibutton(app.GridLayout, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.ObjectDetectionButton = uibutton(app.GridLayout, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionButtonPushed, true);
            app.ObjectDetectionButton.Text = 'Object Detection';
            app.MatchDiscriminationButton = uibutton(app.GridLayout, 'push');
            app.MatchDiscriminationButton.Text = 'Match Discrimination';
            app.ShowFrameButton = uibutton(app.GridLayout, 'push');
            app.ShowFrameButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFrameButtonPushed, true);
            app.ShowFrameButton.Text = 'Show Frame';
            app.FrameNumber = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end