classdef projectTest_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        FrameNumber                matlab.ui.control.EditField
        ShowFrameButton            matlab.ui.control.Button
        MatchDiscriminationButton  matlab.ui.control.Button
        ObjectDetectionButton      matlab.ui.control.Button
        LoadFolderButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Maindir; %main directory
        image_files; %frames of selected folder
        numFrames; %number of frames in the folder
        binary_images; %list of binary images after small object detection
        final;
        grayimg;
        region_grown;
        res;
    end
    
    methods (Access = private)
        
        function data = getFrameData(app, frameNumber)
            %GETFRAMEDATA retrieves frame data on object locations
            % frameNumber specifies which frame to analyse

            gtLocation = sprintf("%s\\gt\\gt.txt" ,app.Maindir);
            
            % Variables for loop
            opts = detectImportOptions(gtLocation);
            n = true;
            a = 1;

            % Preallocate data array
            data = zeros(100, 6);
            while n
                % Only read line a of file
                opts.DataLines = [a a];
                A = readmatrix(gtLocation, opts);
                if A(1) > frameNumber
                    n = false;
                end
                if A(1) == frameNumber
                    % Only first six columns are needed
                    data(a,:) = A(1:6);
                end
                a = a+1;
            end
            % Remove rows of zeros
            data( all(~data,2), : ) = [];
        end        

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            %Select Numbered Folder
            app.Maindir = uigetdir("Select Numbered Foldeer");   
            %app.Maindir = '001';
            app.image_files = dir(sprintf('%s\\img\\*.jpg', app.Maindir));
            app.numFrames = length(app.image_files);
%             for i = 1: app.numFrames
%                 path = strcat(app.image_files(i).folder, '\', app.image_files(i).name);
%                 image = imread(path);
%                 imshow(image, 'Parent',app.UIAxes);
%                 drawnow;
%                 pause(0.01)
%             end
            
        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionButtonPushed(app, event)
%              %Split image into 30x30 pixel regions
             %for i= 2: app.numFrames - 1
             i = 2;
                 %intialise images at index i, i-1 and i+1
                path1 = strcat(app.image_files(i).folder, '\', app.image_files(i).name);
                path2 = strcat(app.image_files(i-1).folder, '\', app.image_files(i-1).name);
                path3 = strcat(app.image_files(i+1).folder, '\', app.image_files(i+1).name);
                image1 = imread(path1);
                image2 = imread(path2);
                image3 = imread(path3);
                app.grayimg = rgb2gray(image1);
                gry2 = rgb2gray(image2);
                gry3 = rgb2gray(image3);
                %Split images into regions of 30x30 pixels
                [rows, cols] = size(app.grayimg);
                wholeBlockRows = floor(rows/30);
                blockVectorR = [30*ones(1, wholeBlockRows), rem(rows, 30)];
                wholeBlockCols = floor(cols/30);
                blockVectorC = [30 * ones(1, wholeBlockCols), rem(cols, 30)];
                ca1 = mat2cell(app.grayimg, blockVectorR, blockVectorC);
                ca2 = mat2cell(gry2, blockVectorR, blockVectorC);
                ca3 = mat2cell(gry3, blockVectorR, blockVectorC);
                numBlocksR = size(ca1, 1);
                numBlocksC = size(ca1, 2);
                binary_array = cell(numBlocksR, numBlocksC);
                %Apply algorithm at each block
                for r = 1: numBlocksR
                    for c = 1: numBlocksC
                        diff1 = abs(double(ca1{r,c}) - double(ca2{r,c}));
                        diff2 = abs(double(ca1{r,c}) - double(ca3{r,c}));
                        theta1 = 1/mean(diff1, "all");
                        theta2 = 1/mean(diff2, "all");
                        thresh1 = -log(0.05)/theta1;
                        thresh2 = -log(0.05)/theta2;
                        binary1 = diff1 > thresh1;
                        binary2 = diff2 > thresh2;
                        binary_array{r,c} = binary1.*binary2; 
%                         if c == 1 && r == 1
%                             subplot(1, 4, 1), imshow(binary1);
%                             subplot(1, 4, 2), imshow(binary2);
%                             subplot(1, 4, 3), imshow(binary_array{r,c});
%                             subplot(1, 4, 4), imshow(ca2{r,c});
%                         end
                    end
                end              
                 %display binarized image
                 app.final = cell2mat(binary_array);
                 imshow(app.final, 'Parent', app.UIAxes);
                 %rectangle(app.UIAxes, "Position", [50, 100, 50, 100], "EdgeColor", 'b');
                 drawnow;
                 pause(0.01);
             %end
% %for i = 2: app.numFrames-1
%  i = 2;
%                 path1 = strcat(app.image_files(i).folder, '\', app.image_files(i).name);
%                 path2 = strcat(app.image_files(i-1).folder, '\', app.image_files(i-1).name);
%                 path3 = strcat(app.image_files(i+1).folder, '\', app.image_files(i+1).name);
%                 im1 = imread(path1);
%                 im2 = imread(path2);
%                 im3 = imread(path3);
%                 gry1 = rgb2gray(im1);
%                 gry2 = rgb2gray(im2);
%                 gry3 = rgb2gray(im3);
%                 diff1 = abs(double(gry1)- double(gry2));
%                 diff2 = abs(double(gry1)- double(gry3));
%                 avg1 = mean(diff1, "all");
%                 avg2 = mean(diff2, "all");
%                 thresh1 = -log(0.05)*avg1;
%                 thresh2 = -log(0.05)*avg2;
%                 binary1 = diff1 > thresh1;
%                 binary2 = diff2 > thresh2;
%                 app.final = binary1.*binary2;
%                 imshow(app.final, 'Parent', app.UIAxes);
%                 drawnow;
%                 pause(0.01);
% %end
        end

        % Button pushed function: ShowFrameButton
        function ShowFrameButtonPushed(app, event)
            % Displays Frame
            frame = str2double(app.FrameNumber.Value);
            path = strcat(app.image_files(frame).folder, '\', app.image_files(frame).name);
            image = imread(path);
            data = getFrameData(app, frame);
            %imshow(image, 'Parent', app.UIAxes);
            %rectangle(app.UIAxes, "Position", [50, 1, 50, 100], "EdgeColor", 'b');
            for row = 1:size(data,1)
                posx = data(row, 3);
                posy = data(row, 4);
                width = data(row, 5);
                height = data(row, 6);
                rectangle(app.UIAxes, "Position", [posx, posy, width, height], "EdgeColor", 'y');
            end
        end

        % Button pushed function: MatchDiscriminationButton
        function MatchDiscriminationButtonPushed(app, event)
            %MATCHDISCRIM performs candidate match discrimination on the
            %frame
            %Region Growing
            Hblob = vision.BlobAnalysis('MinimumBlobArea', 4, 'LabelMatrixOutputPort', 300, 'MaximumCount', 300);
            [area, centroid, bbox1] = Hblob(imbinarize(app.final));
            %blobResults = [area, centroid, bbox];
            [rows, cols] = size(app.grayimg);
            app.region_grown = zeros(rows, cols);
            bboxes = zeros(size(centroid, 1), 6);
            for row = 1:size(centroid,1)
                centroidCol = round(centroid(row, 1));
                centroidRow = round(centroid(row, 2));
                lowerRindex = max(1, centroidRow - 5);
                upperRindex = min(rows, centroidRow + 5);
                lowerCindex = max(1, centroidCol - 5);
                upperCindex = min(cols, centroidCol + 5);
                gray_window = app.grayimg(lowerRindex: upperRindex, lowerCindex : upperCindex);
                bin_window = app.final(lowerRindex: upperRindex, lowerCindex : upperCindex);
                bboxes(row, :) = [centroidCol, centroidRow, lowerCindex, lowerRindex, upperCindex - lowerCindex, upperRindex - lowerRindex];
                pixel_values = zeros(numel(gray_window), 1);
                a = 1;
                for m = 1:size(gray_window, 1)
                    for n = 1:size(gray_window, 2)
                        if bin_window(m,n) == 1
                            pixel_values(a) = gray_window(m,n);
                            a = a + 1;
                        end
                    end
                end
                pixel_values = pixel_values(1:a-1);
                mu = mean(double(pixel_values), "all");
                sigma = std(double(pixel_values), 1) / sqrt(size(pixel_values, 1));
                x = norminv([0.005, 0.995], mu, sigma);
%                 if row == 1
%                     a = bboxes(1, :)
%                     b = bin_window
%                     c = gray_window
%                     d = x
%                 end
                for m = 1:size(gray_window, 1)
                    for n = 1:size(gray_window, 2)
                        if gray_window(m,n) > x(1) && gray_window(m,n) < x(2)
                            bin_window(m,n) = 1;
                        else
                            bin_window(m,n) = 0;
                        end
                    end
                end
%                 if row == 1
%                     e = bin_window
%                     f = pixel_values
%                     g = [mu, sigma]
%                 end
                app.region_grown(lowerRindex:upperRindex, lowerCindex: upperCindex) = bin_window;
            end
            imshow(app.region_grown, 'Parent', app.UIAxes);
            
            %Morphological cues
            Hblob = vision.BlobAnalysis('MinimumBlobArea', 10, 'LabelMatrixOutputPort', 300, 'MaximumCount', 300, "MajorAxisLengthOutputPort", true, "EccentricityOutputPort", true, "ExtentOutputPort", true);
            [area, centroid, bbox, majoraxis, eccentricity, extent] = Hblob(imbinarize(app.region_grown));
            toRemove = [];
            app.res = majoraxis;
            for row = 1:size(centroid, 1)
                %filter by extent
                if extent(row) > 0.8
                    toRemove = [toRemove; row];
                    continue;
                end
                %filter by eccentricity
                if eccentricity(row) < 0.5
                    toRemove = [toRemove; row];
                    continue;
                end
                %filter by area
                if area(row) > 40
                    toRemove = [toRemove; row];
                    continue;
                end
%                 filter by major axis
                if majoraxis(row) > 12
                    toRemove = [toRemove; row];
                    continue;
                end
            end
            centroid(toRemove, :) = [];
            bbox(toRemove, :) = []
            %show result
            for row = 1:size(centroid,1)
                posx = bbox1(row, 1);
                posy = bbox1(row, 2);
                width = bbox1(row, 3);
                height = bbox1(row, 4);
                rectangle(app.UIAxes, "Position", [posx, posy, width, height], "EdgeColor", 'r');
                rectangle(app.UIAxes, "Position", [bbox(row, 1), bbox(row, 2), bbox(row, 3), bbox(row, 4)], "EdgeColor", 'b');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadFolderButton = uibutton(app.GridLayout, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.ObjectDetectionButton = uibutton(app.GridLayout, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionButtonPushed, true);
            app.ObjectDetectionButton.Text = 'Object Detection';
            app.MatchDiscriminationButton = uibutton(app.GridLayout, 'push');
            app.MatchDiscriminationButton.ButtonPushedFcn = createCallbackFcn(app, @MatchDiscriminationButtonPushed, true);
            app.MatchDiscriminationButton.Text = 'Match Discrimination';
            app.ShowFrameButton = uibutton(app.GridLayout, 'push');
            app.ShowFrameButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFrameButtonPushed, true);
            app.ShowFrameButton.Text = 'Show Frame';
            app.FrameNumber = uieditfield(app.GridLayout, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectTest_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end